classdef opp_lncRNA < matlab.apps.AppBase

        % Properties that correspond to app components
        properties (Access = public)
                opplncRNAUIFigure            matlab.ui.Figure
                TabGroup                     matlab.ui.container.TabGroup
                lncRNAmicroRNATab            matlab.ui.container.Tab
                TabGroup7                    matlab.ui.container.TabGroup
                DatabaseTab_2                matlab.ui.container.Tab
                DB1_Type                     matlab.ui.container.ButtonGroup
                DB1_Union                    matlab.ui.control.RadioButton
                DB1_Intersect                matlab.ui.control.RadioButton
                DB1_Filter2                  matlab.ui.control.Button
                miRNA_Distance_2             matlab.ui.container.ButtonGroup
                DB1_Above                    matlab.ui.control.NumericEditField
                AboveLabel_2                 matlab.ui.control.Label
                DB1_Filter1                  matlab.ui.control.Button
                Database1                    matlab.ui.container.ButtonGroup
                DB1_LncBase                  matlab.ui.control.CheckBox
                DB1_miRcode                  matlab.ui.control.CheckBox
                DB1_ENCORI                   matlab.ui.control.CheckBox
                lncRNAmicroRNADatabasePanel  matlab.ui.container.Panel
                DB1_Open                     matlab.ui.control.Button
                DB1_Path                     matlab.ui.control.EditField
                ExcelFilePathLabel_13        matlab.ui.control.Label
                Prediction1Tab_2             matlab.ui.container.Tab
                Predict1_Compute             matlab.ui.control.Button
                Predict1_Distance            matlab.ui.container.ButtonGroup
                KEGG_KO_5                    matlab.ui.control.RadioButton
                KEGG_Gene_5                  matlab.ui.control.RadioButton
                KEGG_PathID_5                matlab.ui.control.RadioButton
                CutoffPanel_5                matlab.ui.container.Panel
                Predict1_pValue              matlab.ui.control.NumericEditField
                pValueEditFieldLabel_5       matlab.ui.control.Label
                Predict1_Coefficient         matlab.ui.control.NumericEditField
                CoefficientEditFieldLabel_5  matlab.ui.control.Label
                microRNADataPanel_7          matlab.ui.container.Panel
                Predict1_miRNA_IDCol         matlab.ui.control.NumericEditField
                IDColumnLabel_2              matlab.ui.control.Label
                Predict1_miRNA_DataCol       matlab.ui.control.EditField
                DataColumnEditFieldLabel_10  matlab.ui.control.Label
                Predict1_miRNA_SheetNo       matlab.ui.control.NumericEditField
                SheetNoEditField_2Label_3    matlab.ui.control.Label
                Predict1_miRNA_Open          matlab.ui.control.Button
                Predict1_miRNA_Path          matlab.ui.control.EditField
                ExcelFilePathLabel_16        matlab.ui.control.Label
                lncRNADataPanel              matlab.ui.container.Panel
                Predict1_lncRNA_IDCol        matlab.ui.control.NumericEditField
                IDColumnLabel                matlab.ui.control.Label
                Predict1_lncRNA_DataCol      matlab.ui.control.EditField
                DataColumnEditFieldLabel_9   matlab.ui.control.Label
                Predict1_lncRNA_SheetNo      matlab.ui.control.NumericEditField
                SheetNoEditFieldLabel_7      matlab.ui.control.Label
                Predict1_lncRNA_Open         matlab.ui.control.Button
                Predict1_lncRNA_Path         matlab.ui.control.EditField
                ExcelFilePathLabel_15        matlab.ui.control.Label
                Prediction2Tab_3             matlab.ui.container.Tab
                Predict2_Compute             matlab.ui.control.Button
                Predict2_Distance            matlab.ui.container.ButtonGroup
                KEGG_KO_6                    matlab.ui.control.RadioButton
                KEGG_Gene_6                  matlab.ui.control.RadioButton
                KEGG_PathID_6                matlab.ui.control.RadioButton
                CutoffPanel_6                matlab.ui.container.Panel
                Predict2_pValue              matlab.ui.control.NumericEditField
                pValueEditFieldLabel_6       matlab.ui.control.Label
                Predict2_Coefficient         matlab.ui.control.NumericEditField
                CoefficientEditFieldLabel_6  matlab.ui.control.Label
                PredictionResultFilteredbylncRNAmicroRNADatabasePanel  matlab.ui.container.Panel
                Predict2_DB_Open             matlab.ui.control.Button
                Predict2_DB_Path             matlab.ui.control.EditField
                ExcelFilePathLabel_19        matlab.ui.control.Label
                microRNADataPanel_8          matlab.ui.container.Panel
                Predict2_miRNA_IDCol         matlab.ui.control.NumericEditField
                IDColumnLabel_4              matlab.ui.control.Label
                Predict2_miRNA_DataCol       matlab.ui.control.EditField
                DataColumnEditFieldLabel_12  matlab.ui.control.Label
                Predict2_miRNA_SheetNo       matlab.ui.control.NumericEditField
                SheetNoEditFieldLabel_9      matlab.ui.control.Label
                Predict2_miRNA_Open          matlab.ui.control.Button
                Predict2_miRNA_Path          matlab.ui.control.EditField
                ExcelFilePathLabel_18        matlab.ui.control.Label
                lncRNADataPanel_2            matlab.ui.container.Panel
                Predict2_lncRNA_IDCol        matlab.ui.control.NumericEditField
                IDColumnLabel_3              matlab.ui.control.Label
                Predict2_lncRNA_DataCol      matlab.ui.control.EditField
                DataColumnEditFieldLabel_11  matlab.ui.control.Label
                Predict2_lncRNA_SheetNo      matlab.ui.control.NumericEditField
                SheetNoEditFieldLabel_8      matlab.ui.control.Label
                Predict2_lncRNA_Open         matlab.ui.control.Button
                Predict2_lncRNA_Path         matlab.ui.control.EditField
                ExcelFilePathLabel_17        matlab.ui.control.Label
                DrawGraphTab                 matlab.ui.container.Tab
                lncRNAmicroRNAPairsPanel     matlab.ui.container.Panel
                lncRNA_Draw                  matlab.ui.control.Button
                lncRNA_Open                  matlab.ui.control.Button
                lncRNA_Path                  matlab.ui.control.EditField
                ExcelFilePathLabel_27        matlab.ui.control.Label
                IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel  matlab.ui.container.Panel
                lncRNA_CloseAll              matlab.ui.control.Button
                DrawTopNNetworkLabel         matlab.ui.control.Label
                lncRNA_TopN                  matlab.ui.control.NumericEditField
                TopnLabel                    matlab.ui.control.Label
                lncRNA_DrawTopN              matlab.ui.control.Button
                lncRNA_Separate              matlab.ui.control.Button
                lncRNA_DrawSelect            matlab.ui.control.Button
                lncRNA_Table                 matlab.ui.control.Table
                microRNAmRNATab_3            matlab.ui.container.Tab
                TabGroup8                    matlab.ui.container.TabGroup
                DatabaseTab_3                matlab.ui.container.Tab
                DB2_Type                     matlab.ui.container.ButtonGroup
                DB2_Union                    matlab.ui.control.RadioButton
                DB2_Intersect                matlab.ui.control.RadioButton
                DB2_Filter2                  matlab.ui.control.Button
                miRNA_Distance_3             matlab.ui.container.ButtonGroup
                DB2_Above                    matlab.ui.control.NumericEditField
                AboveLabel_3                 matlab.ui.control.Label
                DB2_Filter1                  matlab.ui.control.Button
                Database2                    matlab.ui.container.ButtonGroup
                DB2_miRecords                matlab.ui.control.CheckBox
                DB2_miRanda                  matlab.ui.control.CheckBox
                DB2_miRDB                    matlab.ui.control.CheckBox
                DB2_miRTarBase               matlab.ui.control.CheckBox
                DB2_TarBase                  matlab.ui.control.CheckBox
                DB2_ENCORI                   matlab.ui.control.CheckBox
                DB2_miRcode                  matlab.ui.control.CheckBox
                microRNAmRNADatabasePanel    matlab.ui.container.Panel
                DB2_Open                     matlab.ui.control.Button
                DB2_Path                     matlab.ui.control.EditField
                ExcelFilePathLabel_14        matlab.ui.control.Label
                Prediction1Tab_3             matlab.ui.container.Tab
                Predict3_Compute             matlab.ui.control.Button
                Predict3_Distance            matlab.ui.container.ButtonGroup
                KEGG_KO_7                    matlab.ui.control.RadioButton
                KEGG_Gene_7                  matlab.ui.control.RadioButton
                KEGG_PathID_7                matlab.ui.control.RadioButton
                CutoffPanel_7                matlab.ui.container.Panel
                Predict3_pValue              matlab.ui.control.NumericEditField
                pValueEditFieldLabel_7       matlab.ui.control.Label
                Predict3_Coefficient         matlab.ui.control.NumericEditField
                CoefficientEditFieldLabel_7  matlab.ui.control.Label
                mRNADataPanel_4              matlab.ui.container.Panel
                Predict3_mRNA_IDCol          matlab.ui.control.NumericEditField
                IDColumnLabel_6              matlab.ui.control.Label
                Predict3_mRNA_DataCol        matlab.ui.control.EditField
                DataColumnEditFieldLabel_14  matlab.ui.control.Label
                Predict3_mRNA_SheetNo        matlab.ui.control.NumericEditField
                SheetNoEditField_2Label_4    matlab.ui.control.Label
                Predict3_mRNA_Open           matlab.ui.control.Button
                Predict3_mRNA_Path           matlab.ui.control.EditField
                ExcelFilePathLabel_21        matlab.ui.control.Label
                microRNADataPanel_9          matlab.ui.container.Panel
                Predict3_miRNA_IDCol         matlab.ui.control.NumericEditField
                IDColumnLabel_5              matlab.ui.control.Label
                Predict3_miRNA_DataCol       matlab.ui.control.EditField
                DataColumnEditFieldLabel_13  matlab.ui.control.Label
                Predict3_miRNA_SheetNo       matlab.ui.control.NumericEditField
                SheetNoEditFieldLabel_10     matlab.ui.control.Label
                Predict3_miRNA_Open          matlab.ui.control.Button
                Predict3_miRNA_Path          matlab.ui.control.EditField
                ExcelFilePathLabel_20        matlab.ui.control.Label
                Prediction2Tab_4             matlab.ui.container.Tab
                Predict4_Compute             matlab.ui.control.Button
                Predict4_Distance            matlab.ui.container.ButtonGroup
                KEGG_KO_8                    matlab.ui.control.RadioButton
                KEGG_Gene_8                  matlab.ui.control.RadioButton
                KEGG_PathID_8                matlab.ui.control.RadioButton
                CutoffPanel_8                matlab.ui.container.Panel
                Predict4_pValue              matlab.ui.control.NumericEditField
                pValueEditFieldLabel_8       matlab.ui.control.Label
                Predict4_Coefficient         matlab.ui.control.NumericEditField
                CoefficientEditFieldLabel_8  matlab.ui.control.Label
                PredictionResultFilteredbymicroRNAmRNADatabasePanel  matlab.ui.container.Panel
                Predict4_DB_Open             matlab.ui.control.Button
                Predict4_DB_Path             matlab.ui.control.EditField
                ExcelFilePathLabel_24        matlab.ui.control.Label
                mRNADataPanel_5              matlab.ui.container.Panel
                Predict4_mRNA_IDCol          matlab.ui.control.NumericEditField
                IDColumnLabel_8              matlab.ui.control.Label
                Predict4_mRNA_DataCol        matlab.ui.control.EditField
                DataColumnEditFieldLabel_16  matlab.ui.control.Label
                Predict4_mRNA_SheetNo        matlab.ui.control.NumericEditField
                SheetNoEditFieldLabel_12     matlab.ui.control.Label
                Predict4_mRNA_Open           matlab.ui.control.Button
                Predict4_mRNA_Path           matlab.ui.control.EditField
                ExcelFilePathLabel_23        matlab.ui.control.Label
                microRNADataPanel_10         matlab.ui.container.Panel
                Predict4_miRNA_IDCol         matlab.ui.control.NumericEditField
                IDColumnLabel_7              matlab.ui.control.Label
                Predict4_miRNA_DataCol       matlab.ui.control.EditField
                DataColumnEditFieldLabel_15  matlab.ui.control.Label
                Predict4_miRNA_SheetNo       matlab.ui.control.NumericEditField
                SheetNoEditFieldLabel_11     matlab.ui.control.Label
                Predict4_miRNA_Open          matlab.ui.control.Button
                Predict4_miRNA_Path          matlab.ui.control.EditField
                ExcelFilePathLabel_22        matlab.ui.control.Label
                DrawGraphTab_2               matlab.ui.container.Tab
                Panel_3                      matlab.ui.container.Panel
                miRNA_CloseAll               matlab.ui.control.Button
                DrawTopNNetworkLabel_4       matlab.ui.control.Label
                miRNA_TopN                   matlab.ui.control.NumericEditField
                TopnLabel_4                  matlab.ui.control.Label
                miRNA_DrawTopN               matlab.ui.control.Button
                miRNA_Separate               matlab.ui.control.Button
                miRNA_DrawSelect             matlab.ui.control.Button
                miRNA_Table                  matlab.ui.control.Table
                microRNAmRNAPairsPanel_2     matlab.ui.container.Panel
                miRNA_Draw                   matlab.ui.control.Button
                miRNA_Open                   matlab.ui.control.Button
                miRNA_Path                   matlab.ui.control.EditField
                ExcelFilePathLabel_28        matlab.ui.control.Label
                MergeTab                     matlab.ui.container.Tab
                Panel_2                      matlab.ui.container.Panel
                ceRNA_Draw                   matlab.ui.control.Button
                ceRNA_CloseAll               matlab.ui.control.Button
                DrawTopNNetworkLabel_3       matlab.ui.control.Label
                ceRNA_TopN                   matlab.ui.control.NumericEditField
                TopnLabel_3                  matlab.ui.control.Label
                ceRNA_DrawTopN               matlab.ui.control.Button
                ceRNA_Separate               matlab.ui.control.Button
                ceRNA_DrawSelect             matlab.ui.control.Button
                ceRNA_Table                  matlab.ui.control.Table
                microRNAmRNAPairsPanel       matlab.ui.container.Panel
                ceRNA_Open2                  matlab.ui.control.Button
                ceRNA_Path2                  matlab.ui.control.EditField
                ExcelFilePathLabel_26        matlab.ui.control.Label
                lncRNAmicroRNAPairsPanel_2   matlab.ui.container.Panel
                ceRNA_Open1                  matlab.ui.control.Button
                ceRNA_Path1                  matlab.ui.control.EditField
                ExcelFilePathLabel_25        matlab.ui.control.Label
        end

        % Callbacks that handle component events
        methods (Access = private)

                % Button pushed function: DB1_Open
                function DB1_OpenButtonPushed(app, event)
                        FullFileName=UI_GetFile({'*.txt','Text File (*.txt)'},'Open lincRNA-microRNA Database File ...');
                        if isempty(FullFileName)
                                return;
                        end
                        app.DB1_Path.Value=FullFileName;
                end

                % Button pushed function: DB1_Filter1
                function DB1_Filter1ButtonPushed(app, event)
                        disp('Processing ...');
                        Selected=app.DB1_Type.SelectedObject;
                        Type=Selected.Text;
                        Type=TrimString(Type);

                        DB=app.DB1_Path.Value;
                        if isempty(DB)
                                return;
                        end
                        Names=UI_CheckPanel(app.Database1);
                        if isempty(Names)
                                disp('Please Select at least One Datbase.');
                        end
                        DB=FilterDatabase(DB,Type,Names{:});
                        assignin('base', 'Database', DB);

                        FullFileName=UI_PutFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Save Filtered Databse to ....');
                        if isempty(FullFileName)
                                return;
                        end
                        writetable(DB, FullFileName,'Delimiter','\t');
                        disp('Finished !');
                end

                % Button pushed function: DB1_Filter2
                function DB1_Filter2ButtonPushed(app, event)
                        disp('Processing ...');
                        DB=app.DB1_Path.Value;
                        if isempty(DB)
                                return;
                        end
                        upto=app.DB1_Above.Value;
                        disp('Loading Database ....');
                        DB=readtable(DB,'delimiter', '\t','ReadVariableNames',1);
                        disp('Finished Loading.');

                        DB=DB(DB.Total>=upto,:);
                        assignin('base', 'Database', DB);

                        FullFileName=UI_PutFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Save Filtered Databse to ....');
                        if isempty(FullFileName)
                                return;
                        end
                        writetable(DB, FullFileName,'Delimiter','\t');
                        disp('Finished !');
                end

                % Button pushed function: DB2_Open
                function DB2_OpenButtonPushed(app, event)
                        FullFileName=UI_GetFile({'*.txt','Text File (*.txt)'},'Open microRNA-mRNA Database File ...');
                        if isempty(FullFileName)
                                return;
                        end
                        app.DB2_Path.Value=FullFileName;
                end

                % Button pushed function: DB2_Filter1
                function DB2_Filter1ButtonPushed(app, event)
                        disp('Processing ...');
                        Selected=app.DB2_Type.SelectedObject;
                        Type=Selected.Text;
                        Type=TrimString(Type);

                        DB=app.DB2_Path.Value;
                        if isempty(DB)
                                return;
                        end
                        Names=UI_CheckPanel(app.Database2);
                        if isempty(Names)
                                msgbox('Please Select at least One Datbase.');
                        end

                        DB=FilterDatabase(DB,Type,Names{:});
                        assignin('base', 'Database', DB);

                        FullFileName=UI_PutFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Save Filtered Databse to ....');
                        if isempty(FullFileName)
                                return;
                        end
                        writetable(DB, FullFileName,'Delimiter','\t');
                        disp('Finished !');
                end

                % Button pushed function: DB2_Filter2
                function DB2_Filter2ButtonPushed(app, event)
                        disp('Processing ...');
                        DB=app.DB2_Path.Value;
                        if isempty(DB)
                                return;
                        end
                        upto=app.DB2_Above.Value;
                        disp('Loading Database ....');
                        DB=readtable(DB,'delimiter', '\t','ReadVariableNames',1);
                        disp('Finished Loading.');

                        DB=DB(DB.Total>=upto,:);
                        assignin('base', 'Database', DB);

                        FullFileName=UI_PutFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Save Filtered Databse to ....');
                        if isempty(FullFileName)
                                return;
                        end
                        writetable(DB, FullFileName,'Delimiter','\t');
                        disp('Finished !');
                end

                % Button pushed function: Predict1_Compute
                function Predict1_ComputeButtonPushed(app, event)
                        disp('Processing ...');
                        Excel=app.Predict1_lncRNA_Path.Value;
                        SheetNo=app.Predict1_lncRNA_SheetNo.Value;
                        IDCol=app.Predict1_lncRNA_IDCol.Value;
                        Column=str2num(app.Predict1_lncRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        lincRNA=Full(:,[IDCol,Column]);

                        Excel=app.Predict1_miRNA_Path.Value;
                        SheetNo=app.Predict1_miRNA_SheetNo.Value;
                        IDCol=app.Predict1_miRNA_IDCol.Value;
                        Column=str2num(app.Predict1_miRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        miRNA=Full(:,[IDCol,Column]);

                        Coefficient=app.Predict1_Coefficient.Value;
                        pValue=app.Predict1_pValue.Value;

                        Selected=app.Predict1_Distance.SelectedObject;
                        Distance=Selected.Text;
                        Distance=TrimString(Distance);

                        Chains=CorrelationChain({lincRNA,miRNA},Distance,Coefficient,pValue);
                        if isempty(Chains)
                                disp('No database match');
                                return;
                        end
                        WriteTable(Chains);
                        assignin('base', 'lincRNA_miRNA', Chains);
                        disp('Finished !');
                end

                % Button pushed function: Predict3_Compute
                function Predict3_ComputeButtonPushed(app, event)
                        disp('Processing ...');
                        Excel=app.Predict3_miRNA_Path.Value;
                        SheetNo=app.Predict3_miRNA_SheetNo.Value;
                        IDCol=app.Predict3_miRNA_IDCol.Value;
                        Column=str2num(app.Predict3_miRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        miRNA=Full(:,[IDCol,Column]);

                        Excel=app.Predict3_mRNA_Path.Value;
                        SheetNo=app.Predict3_mRNA_SheetNo.Value;
                        IDCol=app.Predict3_mRNA_IDCol.Value;
                        Column=str2num(app.Predict3_mRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        mRNA=Full(:,[IDCol,Column]);

                        Coefficient=app.Predict3_Coefficient.Value;
                        pValue=app.Predict3_pValue.Value;

                        Selected=app.Predict3_Distance.SelectedObject;
                        Distance=Selected.Text;
                        Distance=TrimString(Distance);

                        Chains=CorrelationChain({miRNA,mRNA},Distance,Coefficient,pValue);
                        if isempty(Chains)
                                disp('No database match');
                                return;
                        end
                        WriteTable(Chains);
                        assignin('base', 'miRNA_mRNA', Chains);
                        disp('Finished !');
                end

                % Button pushed function: Predict2_Compute
                function Predict2_ComputeButtonPushed(app, event)
                        disp('Processing ...');
                        Excel=app.Predict2_lncRNA_Path.Value;
                        SheetNo=app.Predict2_lncRNA_SheetNo.Value;
                        IDCol=app.Predict2_lncRNA_IDCol.Value;
                        Column=str2num(app.Predict2_lncRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        lincRNA=Full(:,[IDCol,Column]);

                        Excel=app.Predict2_miRNA_Path.Value;
                        SheetNo=app.Predict2_miRNA_SheetNo.Value;
                        IDCol=app.Predict2_miRNA_IDCol.Value;
                        Column=str2num(app.Predict2_miRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        miRNA=Full(:,[IDCol,Column]);

                        DB=app.Predict2_DB_Path.Value;

                        Coefficient=app.Predict2_Coefficient.Value;
                        pValue=app.Predict2_pValue.Value;

                        Selected=app.Predict2_Distance.SelectedObject;
                        Distance=Selected.Text;
                        Distance=TrimString(Distance);

                        Chains=FindCeNetwork({lincRNA,miRNA},Distance,Coefficient,pValue,DB);
                        if isempty(Chains)
                                return;
                        end
                        WriteTable(Chains);
                        assignin('base', 'lincRNA_miRNA', Chains);
                        disp('Finished !');
                end

                % Button pushed function: Predict4_Compute
                function Predict4_ComputeButtonPushed(app, event)
                        disp('Processing ...');
                        Excel=app.Predict4_miRNA_Path.Value;
                        SheetNo=app.Predict4_miRNA_SheetNo.Value;
                        IDCol=app.Predict4_miRNA_IDCol.Value;
                        Column=str2num(app.Predict4_miRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        miRNA=Full(:,[IDCol,Column]);

                        Excel=app.Predict4_mRNA_Path.Value;
                        SheetNo=app.Predict4_mRNA_SheetNo.Value;
                        IDCol=app.Predict4_mRNA_IDCol.Value;
                        Column=str2num(app.Predict4_mRNA_DataCol.Value);
                        Full=xlsRead(Excel,SheetNo);
                        mRNA=Full(:,[IDCol,Column]);

                        DB=app.Predict4_DB_Path.Value;

                        Coefficient=app.Predict4_Coefficient.Value;
                        pValue=app.Predict4_pValue.Value;

                        Selected=app.Predict4_Distance.SelectedObject;
                        Distance=Selected.Text;
                        Distance=TrimString(Distance);

                        Chains=FindCeNetwork({miRNA,mRNA},Distance,Coefficient,pValue,DB);
                        if isempty(Chains)
                                return;
                        end
                        WriteTable(Chains);
                        assignin('base', 'miRNA_mRNA', Chains);
                        disp('Finished !');
                end

                % Button pushed function: Predict4_miRNA_Open
                function Predict4_miRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open microRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict4_miRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict4_mRNA_Open
                function Predict4_mRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open mRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict4_mRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict4_DB_Open
                function Predict4_DB_OpenButtonPushed(app, event)
                        TXT=UI_GetFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Open microRNA-mRNA Pair ....');
                        if isempty(TXT)
                                return;
                        end
                        app.Predict4_DB_Path.Value=TXT;
                end

                % Button pushed function: Predict3_miRNA_Open
                function Predict3_miRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open microRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict3_miRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict3_mRNA_Open
                function Predict3_mRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open mRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict3_mRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict1_lncRNA_Open
                function Predict1_lncRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open lincRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict1_lncRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict1_miRNA_Open
                function Predict1_miRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open miRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict1_miRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict2_lncRNA_Open
                function Predict2_lncRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open lincRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict2_lncRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict2_miRNA_Open
                function Predict2_miRNA_OpenButtonPushed(app, event)
                        Excel=UI_GetFile({'*.xlsx','Excel File (*.xlsx)'},'Open miRNA Raw Data in Exel ...');
                        if isempty(Excel)
                                return;
                        end
                        app.Predict2_miRNA_Path.Value=Excel;
                end

                % Button pushed function: Predict2_DB_Open
                function Predict2_DB_OpenButtonPushed(app, event)
                        TXT=UI_GetFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Open lincRNA-microRNA Pair ....');
                        if isempty(TXT)
                                return;
                        end
                        app.Predict2_DB_Path.Value=TXT;
                end

                % Button pushed function: lncRNA_Open
                function lncRNA_OpenButtonPushed(app, event)
                        TXT=UI_GetFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Open lincRNA-microRNA Pair ....');
                        if isempty(TXT)
                                return;
                        end
                        app.lncRNA_Path.Value=TXT;
                end

                % Button pushed function: miRNA_Open
                function miRNA_OpenButtonPushed(app, event)
                        TXT=UI_GetFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Open microRNA-mRNA Pair ....');
                        if isempty(TXT)
                                return;
                        end
                        app.miRNA_Path.Value=TXT;
                end

                % Button pushed function: lncRNA_Draw
                function lncRNA_DrawButtonPushed(app, event)
                        disp('Processing ...');
                        File=app.lncRNA_Path.Value;
                        if isempty(File)
                                return;
                        end

                        Chains=ReadTable(File);
                        Chains=Chains(:,1:2);

                        Chains=Chains(2:end,:);
                        [NODE,EDGE]=Chain2Network(Chains);
                        if isempty(NODE)
                        	disp('No Networks.');
                        	return;
                        end

                        DisplayNetworks({NODE},{EDGE});
                        xlswrite2('Cytoscape.xlsx',NODE,'Node');
                        xlswrite2('Cytoscape.xlsx',EDGE,'Edge');
                        disp('Finished !');
                end

                % Button pushed function: miRNA_Draw
                function miRNA_DrawButtonPushed(app, event)
                        disp('Processing ...');
                        File=app.miRNA_Path.Value;
                        if isempty(File)
                                return;
                        end

                        Chains=ReadTable(File);
                        Chains=Chains(:,1:2);

                        Chains=Chains(2:end,:);
                        [NODE,EDGE]=Chain2Network(Chains);
                        if isempty(NODE)
                        	disp('No Networks.');
                        	return;
                        end

                        DisplayNetworks({NODE},{EDGE});
                        xlswrite2('Cytoscape.xlsx',NODE,'Node');
                        xlswrite2('Cytoscape.xlsx',EDGE,'Edge');
                        disp('Finished !');
                end

                % Button pushed function: lncRNA_Separate
                function lncRNA_SeparateButtonPushed(app, event)
                        File=app.lncRNA_Path.Value;
                        if isempty(File)
                                return;
                        end

                        Chains=ReadTable(File);
                        Chains=Chains(:,1:2);

                        Chains=Chains(2:end,:);
                        [NODE,EDGE]=Chain2Network(Chains);
                        if isempty(NODE)
                        	disp('No Networks.');
                        	return;
                        end

                        [NODEs,EDGEs,NetNum]=SeparateNetwork(NODE,EDGE);

                        No=num2cell(1:length(NetNum))';
                        NetNum=[No,NetNum];
                        app.lncRNA_Table.Data=NetNum;
                        lincRNA.NODE=NODEs;
                        lincRNA.EDGE=EDGEs;

                        assignin('base', 'lncRNA', lincRNA);
                end

                % Button pushed function: miRNA_Separate
                function miRNA_SeparateButtonPushed(app, event)
                        File=app.miRNA_Path.Value;
                        if isempty(File)
                                return;
                        end

                        Chains=ReadTable(File);
                        Chains=Chains(:,1:2);

                        Chains=Chains(2:end,:);
                        [NODE,EDGE]=Chain2Network(Chains);
                        if isempty(NODE)
                        	disp('No Networks.');
                        	return;
                        end

                        [NODEs,EDGEs,NetNum]=SeparateNetwork(NODE,EDGE);

                        No=num2cell(1:length(NetNum))';
                        NetNum=[No,NetNum];
                        app.miRNA_Table.Data=NetNum;
                        
                        miRNA.NODE=NODEs;
                        miRNA.EDGE=EDGEs;

                        assignin('base', 'miRNA', miRNA);
                end

                % Button pushed function: lncRNA_DrawTopN
                function lncRNA_DrawTopNButtonPushed(app, event)
                        if ~exist('lincRNA')
                                disp('Please Separate Network First ...');
                                return;
                        end

                        lincRNA=evalin('base','lincRNA'); %读取工作区变量
                        NODEs=lincRNA.NODE;
                        EDGEs=lincRNA.EDGE;

                        TopN=app.lncRNA_TopN.Value;

                        for i=1:length(NODEs)
                                if i>TopN
                                        return;
                                end
                                NODE=NODEs{i};
                                EDGE=EDGEs{i};
                                DisplayNetworks({NODE},{EDGE});
                        end
                end

                % Button pushed function: miRNA_DrawTopN
                function miRNA_DrawTopNButtonPushed(app, event)
                        if ~exist('lincRNA')
                        	disp('Please Separate Network First ...');
                        	return;
                        end
                        
                        miRNA=evalin('base','miRNA'); %读取工作区变量
                        NODEs=miRNA.NODE;
                        EDGEs=miRNA.EDGE;

                        TopN=app.miRNA_TopN.Value;

                        for i=1:length(NODEs)
                                if i>TopN
                                        return;
                                end
                                NODE=NODEs{i};
                                EDGE=EDGEs{i};
                                DisplayNetworks({NODE},{EDGE});
                        end
                end

                % Button pushed function: lncRNA_DrawSelect
                function lncRNA_DrawSelectButtonPushed(app, event)
                        if ~IsExistVar('lncRNA')
                         	disp('Please Separate Network First ...');
                        	return;
                        end

                        lncRNA=evalin('base','lncRNA'); %读取工作区变量
                        NODEs=lncRNA.NODE;
                        EDGEs=lncRNA.EDGE;

                        Position=app.lncRNA_Table.Selection;
                        Position=Position(1);

                        NODE=NODEs{Position};
                        EDGE=EDGEs{Position};
                        DisplayNetworks({NODE},{EDGE});
                end

                % Button pushed function: miRNA_DrawSelect
                function miRNA_DrawSelectButtonPushed(app, event)
                        if ~IsExistVar('miRNA')
                        	disp('Please Separate Network First ...');
                        	return;
                        end

                        miRNA=evalin('base','miRNA'); %读取工作区变量
                        NODEs=miRNA.NODE;
                        EDGEs=miRNA.EDGE;

                        Position=app.miRNA_Table.Selection;
                        Position=Position(1);

                        NODE=NODEs{Position};
                        EDGE=EDGEs{Position};
                        DisplayNetworks({NODE},{EDGE});
                end

                % Button pushed function: lncRNA_CloseAll
                function lncRNA_CloseAllButtonPushed(app, event)
                        CH=allchild(0);
                        close(CH);
                end

                % Button pushed function: miRNA_CloseAll
                function miRNA_CloseAllButtonPushed(app, event)
                        CH=allchild(0);
                        close(CH);
                end

                % Button pushed function: ceRNA_CloseAll
                function ceRNA_CloseAllButtonPushed(app, event)
                        CH=allchild(0);
                        close(CH);
                end

                % Button pushed function: ceRNA_Open1
                function ceRNA_Open1ButtonPushed(app, event)
                        TXT=UI_GetFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Open lincRNA-microRNA Pairs ...');
                        if isempty(TXT)
                                return;
                        end
                        app.ceRNA_Path1.Value=TXT;
                end

                % Button pushed function: ceRNA_Open2
                function ceRNA_Open2ButtonPushed(app, event)
                        TXT=UI_GetFile({'*.txt', 'Text File (*.txt)'; '*.*',  'All Files (*.*)'},'Open microRNA-mRNA Pairs ...');
                        if isempty(TXT)
                                return;
                        end
                        app.ceRNA_Path2.Value=TXT;
                end

                % Button pushed function: ceRNA_Draw
                function ceRNA_DrawButtonPushed(app, event)
                        disp('Processing ...');
                        File=app.ceRNA_Path1.Value;
                        if isempty(File)
                                return;
                        end
                        Chains1=ReadTable(File);
                        Chains1=Chains1(:,1:2);

                        File=app.ceRNA_Path2.Value;
                        if isempty(File)
                                return;
                        end
                        Chains2=ReadTable(File);
                        Chains2=Chains2(:,1:2);

                        Chains=InnerJoin(Chains1,Chains2,{'miRNA'});
                        Chains=Chains(2:end,:);

                        [NODE,EDGE]=Chain2Network(Chains);
                        if isempty(NODE)
                                disp('No Networks.');
                                return;
                        end

                        DisplayNetworks({NODE},{EDGE});
                        xlswrite2('Cytoscape.xlsx',NODE,'Node');
                        xlswrite2('Cytoscape.xlsx',EDGE,'Edge');
                        disp('Finished !');
                end

                % Button pushed function: ceRNA_Separate
                function ceRNA_SeparateButtonPushed(app, event)
                        File=app.ceRNA_Path1.Value;
                        if isempty(File)
                                return;
                        end
                        Chains1=ReadTable(File);
                        Chains1=Chains1(:,1:2);

                        File=app.ceRNA_Path2.Value;
                        if isempty(File)
                                return;
                        end
                        Chains2=ReadTable(File);
                        Chains2=Chains2(:,1:2);

                        Chains=InnerJoin(Chains1,Chains2,{'miRNA'});
                        Chains=Chains(2:end,:);
                        [NODE,EDGE]=Chain2Network(Chains);
                        if isempty(NODE)
                        	disp('No Networks.');
                        	return;
                        end

                        [NODEs,EDGEs,NetNum]=SeparateNetwork(NODE,EDGE);

                        No=num2cell(1:length(NetNum))';
                        NetNum=[No,NetNum];
                        app.ceRNA_Table.Data=NetNum;
                        ceRNA.NODE=NODEs;
                        ceRNA.EDGE=EDGEs;

                        assignin('base', 'ceRNA', ceRNA);
                end

                % Button pushed function: ceRNA_DrawTopN
                function ceRNA_DrawTopNButtonPushed(app, event)
                        if ~exist('lincRNA')
                        	disp('Please Separate Network First ...');
                        	return;
                        end
                        
                        ceRNA=evalin('base','ceRNA'); %读取工作区变量
                        NODEs=ceRNA.NODE;
                        EDGEs=ceRNA.EDGE;

                        TopN=app.ceRNA_TopN.Value;

                        for i=1:length(NODEs)
                                if i>TopN
                                        return;
                                end
                                NODE=NODEs{i};
                                EDGE=EDGEs{i};
                                DisplayNetworks({NODE},{EDGE});
                        end
                end

                % Button pushed function: ceRNA_DrawSelect
                function ceRNA_DrawSelectButtonPushed(app, event)
                        if ~IsExistVar('ceRNA')
                        	disp('Please Separate Network First ...');
                        	return;
                        end
                        
                        ceRNA=evalin('base','ceRNA'); %读取工作区变量
                        NODEs=ceRNA.NODE;
                        EDGEs=ceRNA.EDGE;

                        Position=app.ceRNA_Table.Selection;
                        Position=Position(1);

                        NODE=NODEs{Position};
                        EDGE=EDGEs{Position};
                        DisplayNetworks({NODE},{EDGE});
                end
        end

        % Component initialization
        methods (Access = private)

                % Create UIFigure and components
                function createComponents(app)
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                        app.opplncRNAUIFigure = uifigure('Visible', 'off');
                        app.opplncRNAUIFigure.Name = 'opp-lncRNA';
                        app.TabGroup = uitabgroup(app.opplncRNAUIFigure);
                        app.lncRNAmicroRNATab = uitab(app.TabGroup);
                        app.lncRNAmicroRNATab.Title = 'lncRNA-microRNA';
                        app.TabGroup7 = uitabgroup(app.lncRNAmicroRNATab);
                        app.DatabaseTab_2 = uitab(app.TabGroup7);
                        app.DatabaseTab_2.Title = 'Database';
                        app.lncRNAmicroRNADatabasePanel = uipanel(app.DatabaseTab_2);
                        app.lncRNAmicroRNADatabasePanel.Title = 'lncRNA-microRNADatabase';
                        app.ExcelFilePathLabel_13 = uilabel(app.lncRNAmicroRNADatabasePanel);
                        app.ExcelFilePathLabel_13.Text = 'Databse File Path';
                        app.DB1_Path = uieditfield(app.lncRNAmicroRNADatabasePanel, 'text');
                        app.DB1_Open = uibutton(app.lncRNAmicroRNADatabasePanel, 'push');
                        app.DB1_Open.ButtonPushedFcn = createCallbackFcn(app, @DB1_OpenButtonPushed, true);
                        app.DB1_Open.Text = 'Open';
                        app.Database1 = uibuttongroup(app.DatabaseTab_2);
                        app.Database1.Title = 'Database';
                        app.DB1_ENCORI = uicheckbox(app.Database1);
                        app.DB1_ENCORI.Text = 'ENCORI';
                        app.DB1_ENCORI.Value = true;
                        app.DB1_miRcode = uicheckbox(app.Database1);
                        app.DB1_miRcode.Text = 'miRcode';
                        app.DB1_miRcode.Value = true;
                        app.DB1_LncBase = uicheckbox(app.Database1);
                        app.DB1_LncBase.Text = 'LncBase';
                        app.DB1_LncBase.Value = true;
                        app.DB1_Filter1 = uibutton(app.DatabaseTab_2, 'push');
                        app.DB1_Filter1.ButtonPushedFcn = createCallbackFcn(app, @DB1_Filter1ButtonPushed, true);
                        app.DB1_Filter1.Text = 'Filter';
                        app.miRNA_Distance_2 = uibuttongroup(app.DatabaseTab_2);
                        app.miRNA_Distance_2.Title = 'Support Database';
                        app.AboveLabel_2 = uilabel(app.miRNA_Distance_2);
                        app.AboveLabel_2.Text = 'Above';
                        app.DB1_Above = uieditfield(app.miRNA_Distance_2, 'numeric');
                        app.DB1_Filter2 = uibutton(app.DatabaseTab_2, 'push');
                        app.DB1_Filter2.ButtonPushedFcn = createCallbackFcn(app, @DB1_Filter2ButtonPushed, true);
                        app.DB1_Filter2.Text = 'Filter';
                        app.DB1_Type = uibuttongroup(app.DatabaseTab_2);
                        app.DB1_Type.Title = 'Type';
                        app.DB1_Intersect = uiradiobutton(app.DB1_Type);
                        app.DB1_Intersect.Text = 'Intersect';
                        app.DB1_Intersect.Value = true;
                        app.DB1_Union = uiradiobutton(app.DB1_Type);
                        app.DB1_Union.Text = 'Union';
                        app.Prediction1Tab_2 = uitab(app.TabGroup7);
                        app.Prediction1Tab_2.Title = 'Prediction 1';
                        app.lncRNADataPanel = uipanel(app.Prediction1Tab_2);
                        app.lncRNADataPanel.Title = 'lncRNA Data';
                        app.ExcelFilePathLabel_15 = uilabel(app.lncRNADataPanel);
                        app.ExcelFilePathLabel_15.Text = 'Excel File Path';
                        app.Predict1_lncRNA_Path = uieditfield(app.lncRNADataPanel, 'text');
                        app.Predict1_lncRNA_Open = uibutton(app.lncRNADataPanel, 'push');
                        app.Predict1_lncRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict1_lncRNA_OpenButtonPushed, true);
                        app.Predict1_lncRNA_Open.Text = 'Open';
                        app.SheetNoEditFieldLabel_7 = uilabel(app.lncRNADataPanel);
                        app.SheetNoEditFieldLabel_7.Text = 'Sheet No.';
                        app.Predict1_lncRNA_SheetNo = uieditfield(app.lncRNADataPanel, 'numeric');
                        app.Predict1_lncRNA_SheetNo.Value = 1;
                        app.DataColumnEditFieldLabel_9 = uilabel(app.lncRNADataPanel);
                        app.DataColumnEditFieldLabel_9.Text = 'Data Column';
                        app.Predict1_lncRNA_DataCol = uieditfield(app.lncRNADataPanel, 'text');
                        app.Predict1_lncRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel = uilabel(app.lncRNADataPanel);
                        app.IDColumnLabel.Text = 'ID Column';
                        app.Predict1_lncRNA_IDCol = uieditfield(app.lncRNADataPanel, 'numeric');
                        app.Predict1_lncRNA_IDCol.Value = 1;
                        app.microRNADataPanel_7 = uipanel(app.Prediction1Tab_2);
                        app.microRNADataPanel_7.Title = 'microRNA Data';
                        app.ExcelFilePathLabel_16 = uilabel(app.microRNADataPanel_7);
                        app.ExcelFilePathLabel_16.Text = 'Excel File Path';
                        app.Predict1_miRNA_Path = uieditfield(app.microRNADataPanel_7, 'text');
                        app.Predict1_miRNA_Open = uibutton(app.microRNADataPanel_7, 'push');
                        app.Predict1_miRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict1_miRNA_OpenButtonPushed, true);
                        app.Predict1_miRNA_Open.Text = 'Open';
                        app.SheetNoEditField_2Label_3 = uilabel(app.microRNADataPanel_7);
                        app.SheetNoEditField_2Label_3.Text = 'Sheet No.';
                        app.Predict1_miRNA_SheetNo = uieditfield(app.microRNADataPanel_7, 'numeric');
                        app.Predict1_miRNA_SheetNo.Value = 2;
                        app.DataColumnEditFieldLabel_10 = uilabel(app.microRNADataPanel_7);
                        app.DataColumnEditFieldLabel_10.Text = 'Data Column';
                        app.Predict1_miRNA_DataCol = uieditfield(app.microRNADataPanel_7, 'text');
                        app.Predict1_miRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel_2 = uilabel(app.microRNADataPanel_7);
                        app.IDColumnLabel_2.Text = 'ID Column';
                        app.Predict1_miRNA_IDCol = uieditfield(app.microRNADataPanel_7, 'numeric');
                        app.Predict1_miRNA_IDCol.Value = 1;
                        app.CutoffPanel_5 = uipanel(app.Prediction1Tab_2);
                        app.CutoffPanel_5.Title = 'Cutoff';
                        app.CoefficientEditFieldLabel_5 = uilabel(app.CutoffPanel_5);
                        app.CoefficientEditFieldLabel_5.Text = 'Coefficient';
                        app.Predict1_Coefficient = uieditfield(app.CutoffPanel_5, 'numeric');
                        app.Predict1_Coefficient.Value = -0.9;
                        app.pValueEditFieldLabel_5 = uilabel(app.CutoffPanel_5);
                        app.pValueEditFieldLabel_5.Text = 'p Value';
                        app.Predict1_pValue = uieditfield(app.CutoffPanel_5, 'numeric');
                        app.Predict1_pValue.Value = 0.05;
                        app.Predict1_Distance = uibuttongroup(app.Prediction1Tab_2);
                        app.Predict1_Distance.Title = 'Distance';
                        app.KEGG_PathID_5 = uiradiobutton(app.Predict1_Distance);
                        app.KEGG_PathID_5.Text = 'Pearson';
                        app.KEGG_Gene_5 = uiradiobutton(app.Predict1_Distance);
                        app.KEGG_Gene_5.Text = 'Kendall';
                        app.KEGG_KO_5 = uiradiobutton(app.Predict1_Distance);
                        app.KEGG_KO_5.Text = 'Spearman';
                        app.KEGG_KO_5.Value = true;
                        app.Predict1_Compute = uibutton(app.Prediction1Tab_2, 'push');
                        app.Predict1_Compute.ButtonPushedFcn = createCallbackFcn(app, @Predict1_ComputeButtonPushed, true);
                        app.Predict1_Compute.Text = 'Compute';
                        app.Prediction2Tab_3 = uitab(app.TabGroup7);
                        app.Prediction2Tab_3.Title = 'Prediction 2';
                        app.lncRNADataPanel_2 = uipanel(app.Prediction2Tab_3);
                        app.lncRNADataPanel_2.Title = 'lncRNA Data';
                        app.ExcelFilePathLabel_17 = uilabel(app.lncRNADataPanel_2);
                        app.ExcelFilePathLabel_17.Text = 'Excel File Path';
                        app.Predict2_lncRNA_Path = uieditfield(app.lncRNADataPanel_2, 'text');
                        app.Predict2_lncRNA_Open = uibutton(app.lncRNADataPanel_2, 'push');
                        app.Predict2_lncRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict2_lncRNA_OpenButtonPushed, true);
                        app.Predict2_lncRNA_Open.Text = 'Open';
                        app.SheetNoEditFieldLabel_8 = uilabel(app.lncRNADataPanel_2);
                        app.SheetNoEditFieldLabel_8.Text = 'Sheet No.';
                        app.Predict2_lncRNA_SheetNo = uieditfield(app.lncRNADataPanel_2, 'numeric');
                        app.Predict2_lncRNA_SheetNo.Value = 1;
                        app.DataColumnEditFieldLabel_11 = uilabel(app.lncRNADataPanel_2);
                        app.DataColumnEditFieldLabel_11.Text = 'Data Column';
                        app.Predict2_lncRNA_DataCol = uieditfield(app.lncRNADataPanel_2, 'text');
                        app.Predict2_lncRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel_3 = uilabel(app.lncRNADataPanel_2);
                        app.IDColumnLabel_3.Text = 'ID Column';
                        app.Predict2_lncRNA_IDCol = uieditfield(app.lncRNADataPanel_2, 'numeric');
                        app.Predict2_lncRNA_IDCol.Value = 1;
                        app.microRNADataPanel_8 = uipanel(app.Prediction2Tab_3);
                        app.microRNADataPanel_8.Title = 'microRNA Data';
                        app.ExcelFilePathLabel_18 = uilabel(app.microRNADataPanel_8);
                        app.ExcelFilePathLabel_18.Text = 'Excel File Path';
                        app.Predict2_miRNA_Path = uieditfield(app.microRNADataPanel_8, 'text');
                        app.Predict2_miRNA_Open = uibutton(app.microRNADataPanel_8, 'push');
                        app.Predict2_miRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict2_miRNA_OpenButtonPushed, true);
                        app.Predict2_miRNA_Open.Text = 'Open';
                        app.SheetNoEditFieldLabel_9 = uilabel(app.microRNADataPanel_8);
                        app.SheetNoEditFieldLabel_9.Text = 'Sheet No.';
                        app.Predict2_miRNA_SheetNo = uieditfield(app.microRNADataPanel_8, 'numeric');
                        app.Predict2_miRNA_SheetNo.Value = 2;
                        app.DataColumnEditFieldLabel_12 = uilabel(app.microRNADataPanel_8);
                        app.DataColumnEditFieldLabel_12.Text = 'Data Column';
                        app.Predict2_miRNA_DataCol = uieditfield(app.microRNADataPanel_8, 'text');
                        app.Predict2_miRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel_4 = uilabel(app.microRNADataPanel_8);
                        app.IDColumnLabel_4.Text = 'ID Column';
                        app.Predict2_miRNA_IDCol = uieditfield(app.microRNADataPanel_8, 'numeric');
                        app.Predict2_miRNA_IDCol.Value = 1;
                        app.PredictionResultFilteredbylncRNAmicroRNADatabasePanel = uipanel(app.Prediction2Tab_3);
                        app.PredictionResultFilteredbylncRNAmicroRNADatabasePanel.Title = 'Prediction Result Filtered by: lncRNA-microRNA Database';
                        app.ExcelFilePathLabel_19 = uilabel(app.PredictionResultFilteredbylncRNAmicroRNADatabasePanel);
                        app.ExcelFilePathLabel_19.Text = 'Databse File Path';
                        app.Predict2_DB_Path = uieditfield(app.PredictionResultFilteredbylncRNAmicroRNADatabasePanel, 'text');
                        app.Predict2_DB_Open = uibutton(app.PredictionResultFilteredbylncRNAmicroRNADatabasePanel, 'push');
                        app.Predict2_DB_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict2_DB_OpenButtonPushed, true);
                        app.Predict2_DB_Open.Text = 'Open';
                        app.CutoffPanel_6 = uipanel(app.Prediction2Tab_3);
                        app.CutoffPanel_6.Title = 'Cutoff';
                        app.CoefficientEditFieldLabel_6 = uilabel(app.CutoffPanel_6);
                        app.CoefficientEditFieldLabel_6.Text = 'Coefficient';
                        app.Predict2_Coefficient = uieditfield(app.CutoffPanel_6, 'numeric');
                        app.Predict2_Coefficient.Value = -0.9;
                        app.pValueEditFieldLabel_6 = uilabel(app.CutoffPanel_6);
                        app.pValueEditFieldLabel_6.Text = 'p Value';
                        app.Predict2_pValue = uieditfield(app.CutoffPanel_6, 'numeric');
                        app.Predict2_pValue.Value = 0.05;
                        app.Predict2_Distance = uibuttongroup(app.Prediction2Tab_3);
                        app.Predict2_Distance.Title = 'Distance';
                        app.KEGG_PathID_6 = uiradiobutton(app.Predict2_Distance);
                        app.KEGG_PathID_6.Text = 'Pearson';
                        app.KEGG_Gene_6 = uiradiobutton(app.Predict2_Distance);
                        app.KEGG_Gene_6.Text = 'Kendall';
                        app.KEGG_KO_6 = uiradiobutton(app.Predict2_Distance);
                        app.KEGG_KO_6.Text = 'Spearman';
                        app.KEGG_KO_6.Value = true;
                        app.Predict2_Compute = uibutton(app.Prediction2Tab_3, 'push');
                        app.Predict2_Compute.ButtonPushedFcn = createCallbackFcn(app, @Predict2_ComputeButtonPushed, true);
                        app.Predict2_Compute.Text = 'Compute';
                        app.DrawGraphTab = uitab(app.TabGroup7);
                        app.DrawGraphTab.Title = 'Draw Graph';
                        app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel = uipanel(app.DrawGraphTab);
                        app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel.Title = 'Isolate Whole Network into small Networks, which have no connection';
                        app.lncRNA_Table = uitable(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel);
                        app.lncRNA_Table.ColumnName = {'Network'; 'Nodes Number'};
                        app.lncRNA_Table.RowName = {};
                        app.lncRNA_DrawSelect = uibutton(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel, 'push');
                        app.lncRNA_DrawSelect.ButtonPushedFcn = createCallbackFcn(app, @lncRNA_DrawSelectButtonPushed, true);
                        app.lncRNA_DrawSelect.Text = 'Draw Selected Network';
                        app.lncRNA_Separate = uibutton(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel, 'push');
                        app.lncRNA_Separate.ButtonPushedFcn = createCallbackFcn(app, @lncRNA_SeparateButtonPushed, true);
                        app.lncRNA_Separate.Text = 'Separate Whole Network';
                        app.lncRNA_DrawTopN = uibutton(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel, 'push');
                        app.lncRNA_DrawTopN.ButtonPushedFcn = createCallbackFcn(app, @lncRNA_DrawTopNButtonPushed, true);
                        app.lncRNA_DrawTopN.Text = 'Draw';
                        app.TopnLabel = uilabel(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel);
                        app.TopnLabel.Text = 'Top N';
                        app.lncRNA_TopN = uieditfield(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel, 'numeric');
                        app.lncRNA_TopN.Value = 10;
                        app.DrawTopNNetworkLabel = uilabel(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel);
                        app.DrawTopNNetworkLabel.Text = 'Draw Top N Network';
                        app.lncRNA_CloseAll = uibutton(app.IsolateWholeNetworkintosmallNetworkswhichhavenoconnectionPanel, 'push');
                        app.lncRNA_CloseAll.ButtonPushedFcn = createCallbackFcn(app, @lncRNA_CloseAllButtonPushed, true);
                        app.lncRNA_CloseAll.Text = 'Close All Windows';
                        app.lncRNAmicroRNAPairsPanel = uipanel(app.DrawGraphTab);
                        app.lncRNAmicroRNAPairsPanel.Title = 'lncRNA-microRNA Pairs';
                        app.ExcelFilePathLabel_27 = uilabel(app.lncRNAmicroRNAPairsPanel);
                        app.ExcelFilePathLabel_27.Text = 'Databse File Path';
                        app.lncRNA_Path = uieditfield(app.lncRNAmicroRNAPairsPanel, 'text');
                        app.lncRNA_Open = uibutton(app.lncRNAmicroRNAPairsPanel, 'push');
                        app.lncRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @lncRNA_OpenButtonPushed, true);
                        app.lncRNA_Open.Text = 'Open';
                        app.lncRNA_Draw = uibutton(app.lncRNAmicroRNAPairsPanel, 'push');
                        app.lncRNA_Draw.ButtonPushedFcn = createCallbackFcn(app, @lncRNA_DrawButtonPushed, true);
                        app.lncRNA_Draw.Text = 'Draw';
                        app.microRNAmRNATab_3 = uitab(app.TabGroup);
                        app.microRNAmRNATab_3.Title = 'microRNA-mRNA';
                        app.TabGroup8 = uitabgroup(app.microRNAmRNATab_3);
                        app.DatabaseTab_3 = uitab(app.TabGroup8);
                        app.DatabaseTab_3.Title = 'Database';
                        app.microRNAmRNADatabasePanel = uipanel(app.DatabaseTab_3);
                        app.microRNAmRNADatabasePanel.Title = 'microRNA-mRNA Database';
                        app.ExcelFilePathLabel_14 = uilabel(app.microRNAmRNADatabasePanel);
                        app.ExcelFilePathLabel_14.Text = 'Databse File Path';
                        app.DB2_Path = uieditfield(app.microRNAmRNADatabasePanel, 'text');
                        app.DB2_Open = uibutton(app.microRNAmRNADatabasePanel, 'push');
                        app.DB2_Open.ButtonPushedFcn = createCallbackFcn(app, @DB2_OpenButtonPushed, true);
                        app.DB2_Open.Text = 'Open';
                        app.Database2 = uibuttongroup(app.DatabaseTab_3);
                        app.Database2.Title = 'Database';
                        app.DB2_miRcode = uicheckbox(app.Database2);
                        app.DB2_miRcode.Text = 'miRcode';
                        app.DB2_miRcode.Value = true;
                        app.DB2_ENCORI = uicheckbox(app.Database2);
                        app.DB2_ENCORI.Text = 'ENCORI';
                        app.DB2_ENCORI.Value = true;
                        app.DB2_TarBase = uicheckbox(app.Database2);
                        app.DB2_TarBase.Text = 'TarBase';
                        app.DB2_TarBase.Value = true;
                        app.DB2_miRTarBase = uicheckbox(app.Database2);
                        app.DB2_miRTarBase.Text = 'miRTarBase';
                        app.DB2_miRTarBase.Value = true;
                        app.DB2_miRDB = uicheckbox(app.Database2);
                        app.DB2_miRDB.Text = 'miRDB';
                        app.DB2_miRDB.Value = true;
                        app.DB2_miRanda = uicheckbox(app.Database2);
                        app.DB2_miRanda.Text = 'miRanda';
                        app.DB2_miRanda.Value = true;
                        app.DB2_miRecords = uicheckbox(app.Database2);
                        app.DB2_miRecords.Text = 'miRecords';
                        app.DB2_miRecords.Value = true;
                        app.DB2_Filter1 = uibutton(app.DatabaseTab_3, 'push');
                        app.DB2_Filter1.ButtonPushedFcn = createCallbackFcn(app, @DB2_Filter1ButtonPushed, true);
                        app.DB2_Filter1.Text = 'Filter';
                        app.miRNA_Distance_3 = uibuttongroup(app.DatabaseTab_3);
                        app.miRNA_Distance_3.Title = 'Support Database';
                        app.AboveLabel_3 = uilabel(app.miRNA_Distance_3);
                        app.AboveLabel_3.Text = 'Above';
                        app.DB2_Above = uieditfield(app.miRNA_Distance_3, 'numeric');
                        app.DB2_Filter2 = uibutton(app.DatabaseTab_3, 'push');
                        app.DB2_Filter2.ButtonPushedFcn = createCallbackFcn(app, @DB2_Filter2ButtonPushed, true);
                        app.DB2_Filter2.Text = 'Filter';
                        app.DB2_Type = uibuttongroup(app.DatabaseTab_3);
                        app.DB2_Type.Title = 'Type';
                        app.DB2_Intersect = uiradiobutton(app.DB2_Type);
                        app.DB2_Intersect.Text = 'Intersect';
                        app.DB2_Intersect.Value = true;
                        app.DB2_Union = uiradiobutton(app.DB2_Type);
                        app.DB2_Union.Text = 'Union';
                        app.Prediction1Tab_3 = uitab(app.TabGroup8);
                        app.Prediction1Tab_3.Title = 'Prediction 1';
                        app.microRNADataPanel_9 = uipanel(app.Prediction1Tab_3);
                        app.microRNADataPanel_9.Title = 'microRNA Data';
                        app.ExcelFilePathLabel_20 = uilabel(app.microRNADataPanel_9);
                        app.ExcelFilePathLabel_20.Text = 'Excel File Path';
                        app.Predict3_miRNA_Path = uieditfield(app.microRNADataPanel_9, 'text');
                        app.Predict3_miRNA_Open = uibutton(app.microRNADataPanel_9, 'push');
                        app.Predict3_miRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict3_miRNA_OpenButtonPushed, true);
                        app.Predict3_miRNA_Open.Text = 'Open';
                        app.SheetNoEditFieldLabel_10 = uilabel(app.microRNADataPanel_9);
                        app.SheetNoEditFieldLabel_10.Text = 'Sheet No.';
                        app.Predict3_miRNA_SheetNo = uieditfield(app.microRNADataPanel_9, 'numeric');
                        app.Predict3_miRNA_SheetNo.Value = 2;
                        app.DataColumnEditFieldLabel_13 = uilabel(app.microRNADataPanel_9);
                        app.DataColumnEditFieldLabel_13.Text = 'Data Column';
                        app.Predict3_miRNA_DataCol = uieditfield(app.microRNADataPanel_9, 'text');
                        app.Predict3_miRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel_5 = uilabel(app.microRNADataPanel_9);
                        app.IDColumnLabel_5.Text = 'ID Column';
                        app.Predict3_miRNA_IDCol = uieditfield(app.microRNADataPanel_9, 'numeric');
                        app.Predict3_miRNA_IDCol.Value = 1;
                        app.mRNADataPanel_4 = uipanel(app.Prediction1Tab_3);
                        app.mRNADataPanel_4.Title = 'mRNA Data';
                        app.ExcelFilePathLabel_21 = uilabel(app.mRNADataPanel_4);
                        app.ExcelFilePathLabel_21.Text = 'Excel File Path';
                        app.Predict3_mRNA_Path = uieditfield(app.mRNADataPanel_4, 'text');
                        app.Predict3_mRNA_Open = uibutton(app.mRNADataPanel_4, 'push');
                        app.Predict3_mRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict3_mRNA_OpenButtonPushed, true);
                        app.Predict3_mRNA_Open.Text = 'Open';
                        app.SheetNoEditField_2Label_4 = uilabel(app.mRNADataPanel_4);
                        app.SheetNoEditField_2Label_4.Text = 'Sheet No.';
                        app.Predict3_mRNA_SheetNo = uieditfield(app.mRNADataPanel_4, 'numeric');
                        app.Predict3_mRNA_SheetNo.Value = 3;
                        app.DataColumnEditFieldLabel_14 = uilabel(app.mRNADataPanel_4);
                        app.DataColumnEditFieldLabel_14.Text = 'Data Column';
                        app.Predict3_mRNA_DataCol = uieditfield(app.mRNADataPanel_4, 'text');
                        app.Predict3_mRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel_6 = uilabel(app.mRNADataPanel_4);
                        app.IDColumnLabel_6.Text = 'ID Column';
                        app.Predict3_mRNA_IDCol = uieditfield(app.mRNADataPanel_4, 'numeric');
                        app.Predict3_mRNA_IDCol.Value = 1;
                        app.CutoffPanel_7 = uipanel(app.Prediction1Tab_3);
                        app.CutoffPanel_7.Title = 'Cutoff';
                        app.CoefficientEditFieldLabel_7 = uilabel(app.CutoffPanel_7);
                        app.CoefficientEditFieldLabel_7.Text = 'Coefficient';
                        app.Predict3_Coefficient = uieditfield(app.CutoffPanel_7, 'numeric');
                        app.Predict3_Coefficient.Value = -0.9;
                        app.pValueEditFieldLabel_7 = uilabel(app.CutoffPanel_7);
                        app.pValueEditFieldLabel_7.Text = 'p Value';
                        app.Predict3_pValue = uieditfield(app.CutoffPanel_7, 'numeric');
                        app.Predict3_pValue.Value = 0.05;
                        app.Predict3_Distance = uibuttongroup(app.Prediction1Tab_3);
                        app.Predict3_Distance.Title = 'Distance';
                        app.KEGG_PathID_7 = uiradiobutton(app.Predict3_Distance);
                        app.KEGG_PathID_7.Text = 'Pearson';
                        app.KEGG_Gene_7 = uiradiobutton(app.Predict3_Distance);
                        app.KEGG_Gene_7.Text = 'Kendall';
                        app.KEGG_KO_7 = uiradiobutton(app.Predict3_Distance);
                        app.KEGG_KO_7.Text = 'Spearman';
                        app.KEGG_KO_7.Value = true;
                        app.Predict3_Compute = uibutton(app.Prediction1Tab_3, 'push');
                        app.Predict3_Compute.ButtonPushedFcn = createCallbackFcn(app, @Predict3_ComputeButtonPushed, true);
                        app.Predict3_Compute.Text = 'Compute';
                        app.Prediction2Tab_4 = uitab(app.TabGroup8);
                        app.Prediction2Tab_4.Title = 'Prediction 2';
                        app.microRNADataPanel_10 = uipanel(app.Prediction2Tab_4);
                        app.microRNADataPanel_10.Title = 'microRNA Data';
                        app.ExcelFilePathLabel_22 = uilabel(app.microRNADataPanel_10);
                        app.ExcelFilePathLabel_22.Text = 'Excel File Path';
                        app.Predict4_miRNA_Path = uieditfield(app.microRNADataPanel_10, 'text');
                        app.Predict4_miRNA_Open = uibutton(app.microRNADataPanel_10, 'push');
                        app.Predict4_miRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict4_miRNA_OpenButtonPushed, true);
                        app.Predict4_miRNA_Open.Text = 'Open';
                        app.SheetNoEditFieldLabel_11 = uilabel(app.microRNADataPanel_10);
                        app.SheetNoEditFieldLabel_11.Text = 'Sheet No.';
                        app.Predict4_miRNA_SheetNo = uieditfield(app.microRNADataPanel_10, 'numeric');
                        app.Predict4_miRNA_SheetNo.Value = 2;
                        app.DataColumnEditFieldLabel_15 = uilabel(app.microRNADataPanel_10);
                        app.DataColumnEditFieldLabel_15.Text = 'Data Column';
                        app.Predict4_miRNA_DataCol = uieditfield(app.microRNADataPanel_10, 'text');
                        app.Predict4_miRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel_7 = uilabel(app.microRNADataPanel_10);
                        app.IDColumnLabel_7.Text = 'ID Column';
                        app.Predict4_miRNA_IDCol = uieditfield(app.microRNADataPanel_10, 'numeric');
                        app.Predict4_miRNA_IDCol.Value = 1;
                        app.mRNADataPanel_5 = uipanel(app.Prediction2Tab_4);
                        app.mRNADataPanel_5.Title = 'mRNA Data';
                        app.ExcelFilePathLabel_23 = uilabel(app.mRNADataPanel_5);
                        app.ExcelFilePathLabel_23.Text = 'Excel File Path';
                        app.Predict4_mRNA_Path = uieditfield(app.mRNADataPanel_5, 'text');
                        app.Predict4_mRNA_Open = uibutton(app.mRNADataPanel_5, 'push');
                        app.Predict4_mRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict4_mRNA_OpenButtonPushed, true);
                        app.Predict4_mRNA_Open.Text = 'Open';
                        app.SheetNoEditFieldLabel_12 = uilabel(app.mRNADataPanel_5);
                        app.SheetNoEditFieldLabel_12.Text = 'Sheet No.';
                        app.Predict4_mRNA_SheetNo = uieditfield(app.mRNADataPanel_5, 'numeric');
                        app.Predict4_mRNA_SheetNo.Value = 3;
                        app.DataColumnEditFieldLabel_16 = uilabel(app.mRNADataPanel_5);
                        app.DataColumnEditFieldLabel_16.Text = 'Data Column';
                        app.Predict4_mRNA_DataCol = uieditfield(app.mRNADataPanel_5, 'text');
                        app.Predict4_mRNA_DataCol.Value = '2:11';
                        app.IDColumnLabel_8 = uilabel(app.mRNADataPanel_5);
                        app.IDColumnLabel_8.Text = 'ID Column';
                        app.Predict4_mRNA_IDCol = uieditfield(app.mRNADataPanel_5, 'numeric');
                        app.Predict4_mRNA_IDCol.Value = 1;
                        app.PredictionResultFilteredbymicroRNAmRNADatabasePanel = uipanel(app.Prediction2Tab_4);
                        app.PredictionResultFilteredbymicroRNAmRNADatabasePanel.Title = 'Prediction Result Filtered by: microRNA-mRNA Database';
                        app.ExcelFilePathLabel_24 = uilabel(app.PredictionResultFilteredbymicroRNAmRNADatabasePanel);
                        app.ExcelFilePathLabel_24.Text = 'Databse File Path';
                        app.Predict4_DB_Path = uieditfield(app.PredictionResultFilteredbymicroRNAmRNADatabasePanel, 'text');
                        app.Predict4_DB_Open = uibutton(app.PredictionResultFilteredbymicroRNAmRNADatabasePanel, 'push');
                        app.Predict4_DB_Open.ButtonPushedFcn = createCallbackFcn(app, @Predict4_DB_OpenButtonPushed, true);
                        app.Predict4_DB_Open.Text = 'Open';
                        app.CutoffPanel_8 = uipanel(app.Prediction2Tab_4);
                        app.CutoffPanel_8.Title = 'Cutoff';
                        app.CoefficientEditFieldLabel_8 = uilabel(app.CutoffPanel_8);
                        app.CoefficientEditFieldLabel_8.Text = 'Coefficient';
                        app.Predict4_Coefficient = uieditfield(app.CutoffPanel_8, 'numeric');
                        app.Predict4_Coefficient.Value = -0.9;
                        app.pValueEditFieldLabel_8 = uilabel(app.CutoffPanel_8);
                        app.pValueEditFieldLabel_8.Text = 'p Value';
                        app.Predict4_pValue = uieditfield(app.CutoffPanel_8, 'numeric');
                        app.Predict4_pValue.Value = 0.05;
                        app.Predict4_Distance = uibuttongroup(app.Prediction2Tab_4);
                        app.Predict4_Distance.Title = 'Distance';
                        app.KEGG_PathID_8 = uiradiobutton(app.Predict4_Distance);
                        app.KEGG_PathID_8.Text = 'Pearson';
                        app.KEGG_Gene_8 = uiradiobutton(app.Predict4_Distance);
                        app.KEGG_Gene_8.Text = 'Kendall';
                        app.KEGG_KO_8 = uiradiobutton(app.Predict4_Distance);
                        app.KEGG_KO_8.Text = 'Spearman';
                        app.KEGG_KO_8.Value = true;
                        app.Predict4_Compute = uibutton(app.Prediction2Tab_4, 'push');
                        app.Predict4_Compute.ButtonPushedFcn = createCallbackFcn(app, @Predict4_ComputeButtonPushed, true);
                        app.Predict4_Compute.Text = 'Compute';
                        app.DrawGraphTab_2 = uitab(app.TabGroup8);
                        app.DrawGraphTab_2.Title = 'Draw Graph';
                        app.microRNAmRNAPairsPanel_2 = uipanel(app.DrawGraphTab_2);
                        app.microRNAmRNAPairsPanel_2.Title = 'microRNA-mRNA Pairs';
                        app.ExcelFilePathLabel_28 = uilabel(app.microRNAmRNAPairsPanel_2);
                        app.ExcelFilePathLabel_28.Text = 'Databse File Path';
                        app.miRNA_Path = uieditfield(app.microRNAmRNAPairsPanel_2, 'text');
                        app.miRNA_Open = uibutton(app.microRNAmRNAPairsPanel_2, 'push');
                        app.miRNA_Open.ButtonPushedFcn = createCallbackFcn(app, @miRNA_OpenButtonPushed, true);
                        app.miRNA_Open.Text = 'Open';
                        app.miRNA_Draw = uibutton(app.microRNAmRNAPairsPanel_2, 'push');
                        app.miRNA_Draw.ButtonPushedFcn = createCallbackFcn(app, @miRNA_DrawButtonPushed, true);
                        app.miRNA_Draw.Text = 'Draw';
                        app.Panel_3 = uipanel(app.DrawGraphTab_2);
                        app.Panel_3.Title = 'Isolate Whole Network into small Networks, which have no connection';
                        app.miRNA_Table = uitable(app.Panel_3);
                        app.miRNA_Table.ColumnName = {'Network'; 'Nodes Number'};
                        app.miRNA_Table.RowName = {};
                        app.miRNA_DrawSelect = uibutton(app.Panel_3, 'push');
                        app.miRNA_DrawSelect.ButtonPushedFcn = createCallbackFcn(app, @miRNA_DrawSelectButtonPushed, true);
                        app.miRNA_DrawSelect.Text = 'Draw Selected Network';
                        app.miRNA_Separate = uibutton(app.Panel_3, 'push');
                        app.miRNA_Separate.ButtonPushedFcn = createCallbackFcn(app, @miRNA_SeparateButtonPushed, true);
                        app.miRNA_Separate.Text = 'Separate Whole Network';
                        app.miRNA_DrawTopN = uibutton(app.Panel_3, 'push');
                        app.miRNA_DrawTopN.ButtonPushedFcn = createCallbackFcn(app, @miRNA_DrawTopNButtonPushed, true);
                        app.miRNA_DrawTopN.Text = 'Draw';
                        app.TopnLabel_4 = uilabel(app.Panel_3);
                        app.TopnLabel_4.Text = 'Top N';
                        app.miRNA_TopN = uieditfield(app.Panel_3, 'numeric');
                        app.miRNA_TopN.Value = 10;
                        app.DrawTopNNetworkLabel_4 = uilabel(app.Panel_3);
                        app.DrawTopNNetworkLabel_4.Text = 'Draw Top N Network';
                        app.miRNA_CloseAll = uibutton(app.Panel_3, 'push');
                        app.miRNA_CloseAll.ButtonPushedFcn = createCallbackFcn(app, @miRNA_CloseAllButtonPushed, true);
                        app.miRNA_CloseAll.Text = 'Close All Windows';
                        app.MergeTab = uitab(app.TabGroup);
                        app.MergeTab.Title = 'Merge';
                        app.lncRNAmicroRNAPairsPanel_2 = uipanel(app.MergeTab);
                        app.lncRNAmicroRNAPairsPanel_2.Title = 'lncRNA-microRNA Pairs';
                        app.ExcelFilePathLabel_25 = uilabel(app.lncRNAmicroRNAPairsPanel_2);
                        app.ExcelFilePathLabel_25.Text = 'Databse File Path';
                        app.ceRNA_Path1 = uieditfield(app.lncRNAmicroRNAPairsPanel_2, 'text');
                        app.ceRNA_Open1 = uibutton(app.lncRNAmicroRNAPairsPanel_2, 'push');
                        app.ceRNA_Open1.ButtonPushedFcn = createCallbackFcn(app, @ceRNA_Open1ButtonPushed, true);
                        app.ceRNA_Open1.Text = 'Open';
                        app.microRNAmRNAPairsPanel = uipanel(app.MergeTab);
                        app.microRNAmRNAPairsPanel.Title = 'microRNA-mRNA Pairs';
                        app.ExcelFilePathLabel_26 = uilabel(app.microRNAmRNAPairsPanel);
                        app.ExcelFilePathLabel_26.Text = 'Databse File Path';
                        app.ceRNA_Path2 = uieditfield(app.microRNAmRNAPairsPanel, 'text');
                        app.ceRNA_Open2 = uibutton(app.microRNAmRNAPairsPanel, 'push');
                        app.ceRNA_Open2.ButtonPushedFcn = createCallbackFcn(app, @ceRNA_Open2ButtonPushed, true);
                        app.ceRNA_Open2.Text = 'Open';
                        app.Panel_2 = uipanel(app.MergeTab);
                        app.Panel_2.Title = 'Isolate Whole Network into small Networks, which have no connection';
                        app.ceRNA_Table = uitable(app.Panel_2);
                        app.ceRNA_Table.ColumnName = {'Network'; 'Nodes Number'};
                        app.ceRNA_Table.RowName = {};
                        app.ceRNA_DrawSelect = uibutton(app.Panel_2, 'push');
                        app.ceRNA_DrawSelect.ButtonPushedFcn = createCallbackFcn(app, @ceRNA_DrawSelectButtonPushed, true);
                        app.ceRNA_DrawSelect.Text = 'Draw Selected Network';
                        app.ceRNA_Separate = uibutton(app.Panel_2, 'push');
                        app.ceRNA_Separate.ButtonPushedFcn = createCallbackFcn(app, @ceRNA_SeparateButtonPushed, true);
                        app.ceRNA_Separate.Text = 'Separate Whole Network';
                        app.ceRNA_DrawTopN = uibutton(app.Panel_2, 'push');
                        app.ceRNA_DrawTopN.ButtonPushedFcn = createCallbackFcn(app, @ceRNA_DrawTopNButtonPushed, true);
                        app.ceRNA_DrawTopN.Text = 'Draw';
                        app.TopnLabel_3 = uilabel(app.Panel_2);
                        app.TopnLabel_3.Text = 'Top N';
                        app.ceRNA_TopN = uieditfield(app.Panel_2, 'numeric');
                        app.ceRNA_TopN.Value = 10;
                        app.DrawTopNNetworkLabel_3 = uilabel(app.Panel_2);
                        app.DrawTopNNetworkLabel_3.Text = 'Draw Top N Network';
                        app.ceRNA_CloseAll = uibutton(app.Panel_2, 'push');
                        app.ceRNA_CloseAll.ButtonPushedFcn = createCallbackFcn(app, @ceRNA_CloseAllButtonPushed, true);
                        app.ceRNA_CloseAll.Text = 'Close All Windows';
                        app.ceRNA_Draw = uibutton(app.Panel_2, 'push');
                        app.ceRNA_Draw.ButtonPushedFcn = createCallbackFcn(app, @ceRNA_DrawButtonPushed, true);
                        app.ceRNA_Draw.Text = 'Draw Whole Network';
                        % Show the figure after all components are created
                        app.opplncRNAUIFigure.Visible = 'on';
                end
        end

        % App creation and deletion
        methods (Access = public)

                % Construct app
                function app = opp_lncRNA

                        % Create UIFigure and components
                        createComponents(app)

                        % Register the app with App Designer
                        registerApp(app, app.opplncRNAUIFigure)

                        if nargout == 0
                                clear app
                        end
                end

                % Code that executes before app deletion
                function delete(app)

                        % Delete UIFigure when app is deleted
                        delete(app.opplncRNAUIFigure)
                end
        end
end